classdef gamescreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        ComputeristhinkingLabel  matlab.ui.control.Label
        Image_42                 matlab.ui.control.Image
        Image_41                 matlab.ui.control.Image
        Image_40                 matlab.ui.control.Image
        Image_39                 matlab.ui.control.Image
        Image_38                 matlab.ui.control.Image
        Image_37                 matlab.ui.control.Image
        Image_36                 matlab.ui.control.Image
        Image_35                 matlab.ui.control.Image
        Image_34                 matlab.ui.control.Image
        Image_33                 matlab.ui.control.Image
        Image_32                 matlab.ui.control.Image
        Image_31                 matlab.ui.control.Image
        Image_30                 matlab.ui.control.Image
        Image_29                 matlab.ui.control.Image
        Image_28                 matlab.ui.control.Image
        Image_27                 matlab.ui.control.Image
        Image_26                 matlab.ui.control.Image
        Image_25                 matlab.ui.control.Image
        Image_24                 matlab.ui.control.Image
        Image_23                 matlab.ui.control.Image
        Image_22                 matlab.ui.control.Image
        Image_21                 matlab.ui.control.Image
        Image_20                 matlab.ui.control.Image
        Image_19                 matlab.ui.control.Image
        Image_18                 matlab.ui.control.Image
        Image_17                 matlab.ui.control.Image
        Image_16                 matlab.ui.control.Image
        Image_15                 matlab.ui.control.Image
        Image_14                 matlab.ui.control.Image
        Image_13                 matlab.ui.control.Image
        Image_12                 matlab.ui.control.Image
        Image_11                 matlab.ui.control.Image
        Image_10                 matlab.ui.control.Image
        Image_9                  matlab.ui.control.Image
        Image_8                  matlab.ui.control.Image
        Image_7                  matlab.ui.control.Image
        Image_6                  matlab.ui.control.Image
        Image_5                  matlab.ui.control.Image
        Image_4                  matlab.ui.control.Image
        Image_3                  matlab.ui.control.Image
        Image_2                  matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        GameBoard % Store the Board.m
    end
    
    methods (Access = public)
        
        function app = updateUI(app, column)
            if app.GameBoard.player == 2 && ~isempty(app.GameBoard.ai)
                hideControls(app);
                pause(app.GameBoard.ai.strategy.fakeDelay);
                app.GameBoard = app.GameBoard.updateGame(app.GameBoard.ai.strategy.move(app.GameBoard));
                showControls(app);
            else
                app.GameBoard = app.GameBoard.updateGame(column);
            end
            boardState = app.GameBoard.boardState;
            boardState = reshape(boardState.', 1, []);
            valueSet = ["IMG_0555.PNG", "IMG_0553.PNG","IMG_0554.PNG"];
            horizBoard = [valueSet(boardState + 1)];

            % Thanks to Stephen on mathworks.com thread 16996
            [app.Image.ImageSource, app.Image_2.ImageSource, app.Image_3.ImageSource,... 
                app.Image_4.ImageSource, app.Image_5.ImageSource, app.Image_6.ImageSource,... 
                app.Image_7.ImageSource, app.Image_8.ImageSource, app.Image_9.ImageSource,... 
                app.Image_10.ImageSource, app.Image_11.ImageSource, app.Image_12.ImageSource,... 
                app.Image_13.ImageSource, app.Image_14.ImageSource, app.Image_15.ImageSource,... 
                app.Image_16.ImageSource, app.Image_17.ImageSource, app.Image_18.ImageSource,... 
                app.Image_19.ImageSource, app.Image_20.ImageSource, app.Image_21.ImageSource,... 
                app.Image_22.ImageSource, app.Image_23.ImageSource, app.Image_24.ImageSource,... 
                app.Image_25.ImageSource, app.Image_26.ImageSource, app.Image_27.ImageSource,... 
                app.Image_28.ImageSource, app.Image_29.ImageSource, app.Image_30.ImageSource,... 
                app.Image_31.ImageSource, app.Image_32.ImageSource, app.Image_33.ImageSource,... 
                app.Image_34.ImageSource, app.Image_35.ImageSource, app.Image_36.ImageSource,... 
                app.Image_37.ImageSource, app.Image_38.ImageSource, app.Image_39.ImageSource,... 
                app.Image_40.ImageSource, app.Image_41.ImageSource, app.Image_42.ImageSource] = subsref(horizBoard, substruct('{}',{':'}));

            if app.GameBoard.gameState ~= GameState.CONTINUE
                if app.GameBoard.gameState == GameState.PLAYER_1_WIN
                    p1wins
                elseif app.GameBoard.gameState == GameState.PLAYER_2_WIN
                    p2wins
                elseif app.GameBoard.gameState == GameState.DRAW
                    enddraw
                end
            elseif app.GameBoard.player == 2 && ~isempty(app.GameBoard.ai)
                updateUI(app);
            end
        end
    end
    
    methods (Access = private)
        
        function app = hideControls(app)
            app.Button.Visible = "off";
            app.Button_2.Visible = "off";
            app.Button_3.Visible = "off";
            app.Button_4.Visible = "off";
            app.Button_5.Visible = "off";
            app.Button_6.Visible = "off";
            app.Button_7.Visible = "off";             
            app.ComputeristhinkingLabel.Visible = "on";
        end

        function app = showControls(app)
            app.ComputeristhinkingLabel.Visible = "off";
            app.Button.Visible = "on";
            app.Button_2.Visible = "on";
            app.Button_3.Visible = "on";
            app.Button_4.Visible = "on";
            app.Button_5.Visible = "on";
            app.Button_6.Visible = "on";
            app.Button_7.Visible = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, startingPlayer, ai)
            app.ComputeristhinkingLabel.Visible = "off";
            app.GameBoard = Board(startingPlayer, ai);
            if app.GameBoard.player == 2 && ~isempty(app.GameBoard.ai)
                updateUI(app)
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app = updateUI(app, 1);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app = updateUI(app, 2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app = updateUI(app, 3);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app = updateUI(app, 4);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app = updateUI(app, 5);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app = updateUI(app, 6);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app = updateUI(app, 7);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'IMG_0555.PNG';
            app.Image_2 = uiimage(app.GridLayout);
            app.Image_2.ImageSource = 'IMG_0555.PNG';
            app.Image_3 = uiimage(app.GridLayout);
            app.Image_3.ImageSource = 'IMG_0555.PNG';
            app.Image_4 = uiimage(app.GridLayout);
            app.Image_4.ImageSource = 'IMG_0555.PNG';
            app.Image_5 = uiimage(app.GridLayout);
            app.Image_5.ImageSource = 'IMG_0555.PNG';
            app.Image_6 = uiimage(app.GridLayout);
            app.Image_6.ImageSource = 'IMG_0555.PNG';
            app.Image_7 = uiimage(app.GridLayout);
            app.Image_7.ImageSource = 'IMG_0555.PNG';
            app.Image_8 = uiimage(app.GridLayout);
            app.Image_8.ImageSource = 'IMG_0555.PNG';
            app.Image_9 = uiimage(app.GridLayout);
            app.Image_9.ImageSource = 'IMG_0555.PNG';
            app.Image_10 = uiimage(app.GridLayout);
            app.Image_10.ImageSource = 'IMG_0555.PNG';
            app.Image_11 = uiimage(app.GridLayout);
            app.Image_11.ImageSource = 'IMG_0555.PNG';
            app.Image_12 = uiimage(app.GridLayout);
            app.Image_12.ImageSource = 'IMG_0555.PNG';
            app.Image_13 = uiimage(app.GridLayout);
            app.Image_13.ImageSource = 'IMG_0555.PNG';
            app.Image_14 = uiimage(app.GridLayout);
            app.Image_14.ImageSource = 'IMG_0555.PNG';
            app.Image_15 = uiimage(app.GridLayout);
            app.Image_15.ImageSource = 'IMG_0555.PNG';
            app.Image_16 = uiimage(app.GridLayout);
            app.Image_16.ImageSource = 'IMG_0555.PNG';
            app.Image_17 = uiimage(app.GridLayout);
            app.Image_17.ImageSource = 'IMG_0555.PNG';
            app.Image_18 = uiimage(app.GridLayout);
            app.Image_18.ImageSource = 'IMG_0555.PNG';
            app.Image_19 = uiimage(app.GridLayout);
            app.Image_19.ImageSource = 'IMG_0555.PNG';
            app.Image_20 = uiimage(app.GridLayout);
            app.Image_20.ImageSource = 'IMG_0555.PNG';
            app.Image_21 = uiimage(app.GridLayout);
            app.Image_21.ImageSource = 'IMG_0555.PNG';
            app.Image_22 = uiimage(app.GridLayout);
            app.Image_22.ImageSource = 'IMG_0555.PNG';
            app.Image_23 = uiimage(app.GridLayout);
            app.Image_23.ImageSource = 'IMG_0555.PNG';
            app.Image_24 = uiimage(app.GridLayout);
            app.Image_24.ImageSource = 'IMG_0555.PNG';
            app.Image_25 = uiimage(app.GridLayout);
            app.Image_25.ImageSource = 'IMG_0555.PNG';
            app.Image_26 = uiimage(app.GridLayout);
            app.Image_26.ImageSource = 'IMG_0555.PNG';
            app.Image_27 = uiimage(app.GridLayout);
            app.Image_27.ImageSource = 'IMG_0555.PNG';
            app.Image_28 = uiimage(app.GridLayout);
            app.Image_28.ImageSource = 'IMG_0555.PNG';
            app.Image_29 = uiimage(app.GridLayout);
            app.Image_29.ImageSource = 'IMG_0555.PNG';
            app.Image_30 = uiimage(app.GridLayout);
            app.Image_30.ImageSource = 'IMG_0555.PNG';
            app.Image_31 = uiimage(app.GridLayout);
            app.Image_31.ImageSource = 'IMG_0555.PNG';
            app.Image_32 = uiimage(app.GridLayout);
            app.Image_32.ImageSource = 'IMG_0555.PNG';
            app.Image_33 = uiimage(app.GridLayout);
            app.Image_33.ImageSource = 'IMG_0555.PNG';
            app.Image_34 = uiimage(app.GridLayout);
            app.Image_34.ImageSource = 'IMG_0555.PNG';
            app.Image_35 = uiimage(app.GridLayout);
            app.Image_35.ImageSource = 'IMG_0555.PNG';
            app.Image_36 = uiimage(app.GridLayout);
            app.Image_36.ImageSource = 'IMG_0555.PNG';
            app.Image_37 = uiimage(app.GridLayout);
            app.Image_37.ImageSource = 'IMG_0555.PNG';
            app.Image_38 = uiimage(app.GridLayout);
            app.Image_38.ImageSource = 'IMG_0555.PNG';
            app.Image_39 = uiimage(app.GridLayout);
            app.Image_39.ImageSource = 'IMG_0555.PNG';
            app.Image_40 = uiimage(app.GridLayout);
            app.Image_40.ImageSource = 'IMG_0555.PNG';
            app.Image_41 = uiimage(app.GridLayout);
            app.Image_41.ImageSource = 'IMG_0555.PNG';
            app.Image_42 = uiimage(app.GridLayout);
            app.Image_42.ImageSource = 'IMG_0555.PNG';
            app.ComputeristhinkingLabel = uilabel(app.GridLayout);
            app.ComputeristhinkingLabel.Text = 'Computer is thinking...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gamescreen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end