classdef homescreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TutorialButton          matlab.ui.control.Button
        RulesButton             matlab.ui.control.Button
        ByIsaacKimJessicaHongandJennyWangLabel  matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        PlayersButton           matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        PlayervsComputerButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("strategy"));
            addpath(genpath("ui"));
            addpath(genpath("tutorial"));
        end

        % Button pushed function: PlayervsComputerButton
        function PlayervsComputerButtonPushed(app, event)
            modescreen
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: PlayersButton
        function PlayersButtonPushed(app, event)
            gamescreen(1, [])
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            rules
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: TutorialButton
        function TutorialButtonPushed(app, event)
            tutorial1
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayervsComputerButton = uibutton(app.UIFigure, 'push');
            app.PlayervsComputerButton.ButtonPushedFcn = createCallbackFcn(app, @PlayervsComputerButtonPushed, true);
            app.PlayervsComputerButton.Text = '1 Player (vs Computer)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMG_0543.PNG';
            app.PlayersButton = uibutton(app.UIFigure, 'push');
            app.PlayersButton.ButtonPushedFcn = createCallbackFcn(app, @PlayersButtonPushed, true);
            app.PlayersButton.Text = '2 Players';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'IMG_0549.PNG';
            app.ByIsaacKimJessicaHongandJennyWangLabel = uilabel(app.UIFigure);
            app.ByIsaacKimJessicaHongandJennyWangLabel.Text = 'By Isaac Kim, Jessica Hong, and Jenny Wang';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.TutorialButton = uibutton(app.UIFigure, 'push');
            app.TutorialButton.ButtonPushedFcn = createCallbackFcn(app, @TutorialButtonPushed, true);
            app.TutorialButton.Text = 'Tutorial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = homescreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end