classdef modescreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ImpossibleButton  matlab.ui.control.Button
        EasyButton        matlab.ui.control.Button
        ChooseYourLevelofDifficultyLabel  matlab.ui.control.Label
        HardButton        matlab.ui.control.Button
        MediumButton      matlab.ui.control.Button
        Image             matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MediumButton
        function MediumButtonPushed(app, event)
            chooseorder(StrategyType.MCTS_MEDIUM)
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: HardButton
        function HardButtonPushed(app, event)
            chooseorder(StrategyType.MCTS_HARD)
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: EasyButton
        function EasyButtonPushed(app, event)
            chooseorder(StrategyType.MCTS_EASY)
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: ImpossibleButton
        function ImpossibleButtonPushed(app, event)
            chooseorder(StrategyType.ALPHABETA)
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMG_0549.PNG';
            app.MediumButton = uibutton(app.UIFigure, 'push');
            app.MediumButton.ButtonPushedFcn = createCallbackFcn(app, @MediumButtonPushed, true);
            app.MediumButton.Text = 'Medium';
            app.HardButton = uibutton(app.UIFigure, 'push');
            app.HardButton.ButtonPushedFcn = createCallbackFcn(app, @HardButtonPushed, true);
            app.HardButton.Text = 'Hard';
            app.ChooseYourLevelofDifficultyLabel = uilabel(app.UIFigure);
            app.ChooseYourLevelofDifficultyLabel.Text = 'Choose Your Level of Difficulty:';
            app.EasyButton = uibutton(app.UIFigure, 'push');
            app.EasyButton.ButtonPushedFcn = createCallbackFcn(app, @EasyButtonPushed, true);
            app.EasyButton.Text = 'Easy';
            app.ImpossibleButton = uibutton(app.UIFigure, 'push');
            app.ImpossibleButton.ButtonPushedFcn = createCallbackFcn(app, @ImpossibleButtonPushed, true);
            app.ImpossibleButton.Text = 'Impossible';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modescreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end